classdef errorsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ChooseafunctionButtonGroup      matlab.ui.container.ButtonGroup
        F2                              matlab.ui.control.RadioButton
        F1                              matlab.ui.control.RadioButton
        IntegrationintervalSlider       matlab.ui.control.RangeSlider
        IntegrationintervalSliderLabel  matlab.ui.control.Label
        ErrorheatmapbyAbegginingofintervalandLitslengthPanel  matlab.ui.container.Panel
        ALabel                          matlab.ui.control.Label
        LlengthoftheintervalLabel       matlab.ui.control.Label
        MakeErrorPlotButton             matlab.ui.control.Button
        NumberofsubintervalsNforheatmapButtonGroup  matlab.ui.container.ButtonGroup
        N20                             matlab.ui.control.RadioButton
        N10                             matlab.ui.control.RadioButton
        N5                              matlab.ui.control.RadioButton
        MakeHeatmapButton               matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        funkcja = "F1" % Wybrana funkcja (F1 lub F2)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('functions-examples/')
            addpath('trapezoidal/')
            
        end

        % Button pushed function: MakeHeatmapButton
        function MakeHeatmapButtonPushed(app, event)
            Nselection = app.NumberofsubintervalsNforheatmapButtonGroup.SelectedObject;
                Nselectiontext = Nselection.Text;
                switch Nselectiontext
                    case '5'
                        N = 5;
                    case '10'
                        N = 10;
                    case '20'
                        N = 20;
                end
        
                % Define a range of 'a' values (start of the interval) and 'l' values (length of the interval)
                a_values = -5:1:5;  % Example 'a' values
                l_values = 0.5:0.5:5;  % Example 'l' values
    
                % Initialize the matrix to store errors
                errors = zeros(length(a_values), length(l_values));

            if app.funkcja == "F2"
                % Loop over all combinations of 'a' and 'l' to compute the error
                for i = 1:length(a_values)
                    for j = 1:length(l_values)
                        a = a_values(i);
                        l = l_values(j);
                        b = a + l;  % Compute 'b' as 'a' + 'l'
                        n = N;
                        approximate = trapezoidal_general(a, b, n, @chebyshev_example_2);
                
                        % Calculate the exact integral using MATLAB's integral function
                        exact = integral(@chebyshev_example_2, a, b);
                
                        % Compute the error and store it in the matrix
                        errors(i, j) = abs((exact - approximate)/exact);
                    end
                end
    
                % Create the heatmap on the designated UIAxes
                heatmap(app.ErrorheatmapbyAbegginingofintervalandLitslengthPanel, l_values, a_values, errors);
            end

            if app.funkcja == "F1"
                
    
                % Loop over all combinations of 'a' and 'l' to compute the error
                for i = 1:length(a_values)
                    for j = 1:length(l_values)
                        a = a_values(i);
                        l = l_values(j);
                        b = a + l;  % Compute 'b' as 'a' + 'l'
                        n = N;
                        approximate = trapezoidal_general(a, b, n, @chebyshev_example_1);
                
                        % Calculate the exact integral using MATLAB's integral function
                        exact = integral(@chebyshev_example_1, a, b);
                
                        % Compute the error and store it in the matrix
                        errors(i, j) = abs((exact - approximate)/exact);
                    end
                end
    
                % Create the heatmap on the designated UIAxes
                heatmap(app.ErrorheatmapbyAbegginingofintervalandLitslengthPanel, l_values, a_values, errors);
            end
        end

        % Button pushed function: MakeErrorPlotButton
        function MakeErrorPlotButtonPushed(app, event)
            A = app.IntegrationintervalSlider.Value(1);
            B = app.IntegrationintervalSlider.Value(2);
            
            N = [5 10 15 20 25 30 35 40 45 50]; % ilość interwałów

            errors = zeros(size(N));
            
            if app.funkcja == "F1"
                for i = 1:length(N)
                    approx = trapezoidal_general(A, B, N(i), @chebyshev_example_1);
                    exact = integral(@chebyshev_example_1, A, B);
                    errors(i) = abs((exact - approx)/exact) * 100;
                end
            end

            if app.funkcja == "F2"
                for i = 1:length(N)
                    approx = trapezoidal_general(A, B, N(i), @chebyshev_example_2);
                    exact = integral(@chebyshev_example_2, A, B);
                    errors(i) = abs((exact - approx)/exact) * 100;
                end
            end


            plot(app.UIAxes2, N, errors);
            xlabel(app.UIAxes2, "Number of subintervals")
            ylabel(app.UIAxes2, "Error in %")


        end

        % Selection changed function: ChooseafunctionButtonGroup
        function ChooseafunctionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseafunctionButtonGroup.SelectedObject;
            switch selectedButton.Tag
                case 'F1'
                    app.funkcja = 'F1';
                case 'F2'
                    app.funkcja = 'F2';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, {'Error of trapezoidal method by '; 'N (number of subintervals)'})
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.MakeHeatmapButton = uibutton(app.UIFigure, 'push');
            app.MakeHeatmapButton.ButtonPushedFcn = createCallbackFcn(app, @MakeHeatmapButtonPushed, true);
            app.MakeHeatmapButton.Text = 'MakeHeatmap';
            app.NumberofsubintervalsNforheatmapButtonGroup = uibuttongroup(app.UIFigure);
            app.NumberofsubintervalsNforheatmapButtonGroup.Title = 'Number of subintervals (N) for heatmap';
            app.N5 = uiradiobutton(app.NumberofsubintervalsNforheatmapButtonGroup);
            app.N5.Text = '5';
            app.N5.Value = true;
            app.N10 = uiradiobutton(app.NumberofsubintervalsNforheatmapButtonGroup);
            app.N10.Text = '10';
            app.N20 = uiradiobutton(app.NumberofsubintervalsNforheatmapButtonGroup);
            app.N20.Text = '20';
            app.MakeErrorPlotButton = uibutton(app.UIFigure, 'push');
            app.MakeErrorPlotButton.ButtonPushedFcn = createCallbackFcn(app, @MakeErrorPlotButtonPushed, true);
            app.MakeErrorPlotButton.Text = 'MakeErrorPlot';
            app.ErrorheatmapbyAbegginingofintervalandLitslengthPanel = uipanel(app.UIFigure);
            app.ErrorheatmapbyAbegginingofintervalandLitslengthPanel.Title = 'Error heatmap by A - (beggining of interval) and L (its length)';
            app.LlengthoftheintervalLabel = uilabel(app.ErrorheatmapbyAbegginingofintervalandLitslengthPanel);
            app.LlengthoftheintervalLabel.Text = 'L - length of the interval';
            app.ALabel = uilabel(app.ErrorheatmapbyAbegginingofintervalandLitslengthPanel);
            app.ALabel.Text = 'A';
            app.IntegrationintervalSliderLabel = uilabel(app.UIFigure);
            app.IntegrationintervalSliderLabel.Text = 'Integration interval';
            app.IntegrationintervalSlider = uislider(app.UIFigure, 'range');
            app.IntegrationintervalSlider.Limits = [-10 10];
            app.IntegrationintervalSlider.MajorTicks = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.IntegrationintervalSlider.Value = [-1 1];
            app.ChooseafunctionButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseafunctionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseafunctionButtonGroupSelectionChanged, true);
            app.ChooseafunctionButtonGroup.Title = 'Choose a function';
            app.F1 = uiradiobutton(app.ChooseafunctionButtonGroup);
            app.F1.Tag = 'F1';
            app.F1.Text = 'chebyshev_example_1';
            app.F1.Value = true;
            app.F2 = uiradiobutton(app.ChooseafunctionButtonGroup);
            app.F2.Tag = 'F2';
            app.F2.Text = 'chebyshev_example_2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = errorsGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end