classdef project1GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ErrorHeatmapdependingonabegginingofintervalandlitslengthPanel  matlab.ui.container.Panel
        aSlider              matlab.ui.control.Slider
        aSliderLabel         matlab.ui.control.Label
        MakeErrorPlotButton  matlab.ui.control.Button
        NumberofsubintervalsNforheatmapButtonGroup  matlab.ui.container.ButtonGroup
        N20                  matlab.ui.control.RadioButton
        N10                  matlab.ui.control.RadioButton
        N5                   matlab.ui.control.RadioButton
        MakeHeatmapButton    matlab.ui.control.Button
        UIAxes2              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('/Users/hubert/Documents/MN_MATLAB/Projekt_1/myFunction.m')
            addpath('/Users/hubert/Documents/MN_MATLAB/Projekt_1/trapezoidal_general.m')
        end

        % Button pushed function: MakeHeatmapButton
        function MakeHeatmapButtonPushed(app, event)
            
            Nselection = app.NumberofsubintervalsNforheatmapButtonGroup.SelectedObject;
            Nselectiontext = Nselection.Text;
            switch Nselectiontext
                case '5'
                    N = 5;
                case '10'
                    N = 10;
                case '20'
                    N = 20;
            end


            % Define a range of 'a' values (start of the interval) and 'l' values (length of the interval)
            a_values = -5:1:5;  % Example 'a' values
            l_values = 0.2:0.2:1.4;  % Example 'l' values

            % Initialize the matrix to store errors
            errors = zeros(length(a_values), length(l_values));

            % Loop over all combinations of 'a' and 'l' to compute the error
            for i = 1:length(a_values)
                for j = 1:length(l_values)
                    a = a_values(i);
                    l = l_values(j);
                    b = a + l;  % Compute 'b' as 'a' + 'l'
                    n = N;  % Set the number of subintervals to 20 (or any other value)
                    approximate = trapezoidal_general(a, b, n, @myFunction);
            
                    % Calculate the exact integral using MATLAB's integral function
                    exact = integral(@myFunction, a, b);
            
                    % Compute the error and store it in the matrix
                    errors(i, j) = abs(exact - approximate);
                end
            end

            % Create the heatmap on the designated UIAxes
            
            % heatmap(app.ErrorHeatmapdependingonabegginingofintervalandlitslengthPanel, l_values, a_values, errors);
            
            heatImg = imagesc(l_values, a_values, errors, 'Parent', app.ErrorHeatmapdependingonabegginingofintervalandlitslengthPanel);
            ax = heatImg.Parent;

            xLabel = 'X Axis Label';
            yLabel = 'Y Axis Label';


            xLabelObj = text(ax, 0.5, -0.1, xLabel, 'FontSize', 12, 'HorizontalAlignment', 'center');
            yLabelObj = text(ax,  -0.1, 0.5, yLabel, 'FontSize', 12, 'HorizontalAlignment', 'center', 'Rotation', 90);

            xLabelPos = [length(l_values), -0.1];
            yLabelPos = [-0.1, length(a_values)];

            xLabelObj.Position = xLabelPos;
            yLabelObj.Position = yLabelPos;
        end

        % Button pushed function: MakeErrorPlotButton
        function MakeErrorPlotButtonPushed(app, event)
            Aselected = app.aSlider.Value;
            
            x = linspace(Aselected, 50, 1000); % For example, -10 to 10 with 1000 points
            y = myFunction(x);
            plot(app.UIAxes2, x, y);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, {'Error of trapezoidal method by '; 'N (number of subintervals)'})
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.MakeHeatmapButton = uibutton(app.UIFigure, 'push');
            app.MakeHeatmapButton.ButtonPushedFcn = createCallbackFcn(app, @MakeHeatmapButtonPushed, true);
            app.MakeHeatmapButton.Text = 'MakeHeatmap';
            app.NumberofsubintervalsNforheatmapButtonGroup = uibuttongroup(app.UIFigure);
            app.NumberofsubintervalsNforheatmapButtonGroup.Title = 'Number of subintervals (N) for heatmap';
            app.N5 = uiradiobutton(app.NumberofsubintervalsNforheatmapButtonGroup);
            app.N5.Text = '5';
            app.N5.Value = true;
            app.N10 = uiradiobutton(app.NumberofsubintervalsNforheatmapButtonGroup);
            app.N10.Text = '10';
            app.N20 = uiradiobutton(app.NumberofsubintervalsNforheatmapButtonGroup);
            app.N20.Text = '20';
            app.MakeErrorPlotButton = uibutton(app.UIFigure, 'push');
            app.MakeErrorPlotButton.ButtonPushedFcn = createCallbackFcn(app, @MakeErrorPlotButtonPushed, true);
            app.MakeErrorPlotButton.Text = 'MakeErrorPlot';
            app.aSliderLabel = uilabel(app.UIFigure);
            app.aSliderLabel.Text = 'a';
            app.aSlider = uislider(app.UIFigure);
            app.aSlider.Limits = [-10 10];
            app.ErrorHeatmapdependingonabegginingofintervalandlitslengthPanel = uipanel(app.UIFigure);
            app.ErrorHeatmapdependingonabegginingofintervalandlitslengthPanel.Title = 'Error Heatmap depending on a (beggining of interval) and l (its length)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project1GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end