classdef project1GUI_Adam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ExamplesTab                  matlab.ui.container.Tab
        GenerateExamples             matlab.ui.control.Button
        ExamplesTable                matlab.ui.control.Table
        ErrorsTab                    matlab.ui.container.Tab
        Output                       matlab.ui.control.Table
        StartButton                  matlab.ui.control.Button
        PolydegreeEditField          matlab.ui.control.NumericEditField
        PolydegreeEditFieldLabel     matlab.ui.control.Label
        ExamplesEditField            matlab.ui.control.NumericEditField
        ExamplesEditFieldLabel       matlab.ui.control.Label
        HeatmapTab                   matlab.ui.container.Tab
        PanelS                       matlab.ui.container.Panel
        MaxSubintervalsSpinner       matlab.ui.control.Spinner
        MaxSubintervalsSpinnerLabel  matlab.ui.control.Label
        PlotButton                   matlab.ui.control.Button
        PanelT                       matlab.ui.container.Panel
        StatusValueLabel             matlab.ui.control.Label
        StatusLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        NumberOfExamples % Description
        PolynomialDegree % Description
        NumberOfIntervals % Description
        UIAxes1 % Description
        UIAxes2
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.NumberOfExamples = app.ExamplesEditField.Value;
            app.PolynomialDegree = app.PolydegreeEditField.Value;
            app.StatusValueLabel.Text = "Generating polynomials...";
            app.StatusValueLabel.Text = "Creating Tests...";
            testCases  = createSimpsonTestChebyshev(app.PolynomialDegree, app.NumberOfExamples);
            app.StatusValueLabel.Text = "Testing...";
            [tableData, columnNames] = testChebyshevTable(testCases);
            app.Output.Data = tableData;
            app.Output.ColumnName = columnNames;
            app.StatusValueLabel.Text = "Finished";
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.NumberOfIntervals = app.MaxSubintervalsSpinner.Value;
            coeffs = ones(1,10) .*2;
            [errors_s, errors_t, coeff_indices] = plotN(-1, 1, coeffs, app.NumberOfIntervals);
            % Create the heatmap for Simpson's Rule errors and set titles and labels
            hmapS = heatmap(app.PanelS, 1:app.NumberOfIntervals, coeff_indices, errors_s);
            hmapS.Title = 'Error Heatmap for Simpson''s Rule Integration';
            hmapS.XLabel = 'Number of Subintervals (N)';
            hmapS.YLabel = 'Coefficient Index';
            
            % Create the heatmap for Trapezoidal Rule errors and set titles and labels
            hmapT = heatmap(app.PanelT, 1:app.NumberOfIntervals, coeff_indices, errors_t);
            hmapT.Title = 'Error Heatmap for Trapezoidal Rule Integration';
            hmapT.XLabel = 'Number of Subintervals (N)';
            hmapT.YLabel = 'Coefficient Index';


        end

        % Button pushed function: GenerateExamples
        function GenerateExamplesButtonPushed(app, event)
            [results, columnNames] = Testing_chebyshev_gui();
            app.ExamplesTable.Data = results;
            app.ExamplesTable.ColumnName = columnNames;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.StatusValueLabel = uilabel(app.UIFigure);
            app.StatusValueLabel.Text = 'Status Value';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ExamplesTab = uitab(app.TabGroup);
            app.ExamplesTab.Title = 'Examples';
            app.ExamplesTable = uitable(app.ExamplesTab);
            app.ExamplesTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ExamplesTable.RowName = {};
            app.GenerateExamples = uibutton(app.ExamplesTab, 'push');
            app.GenerateExamples.ButtonPushedFcn = createCallbackFcn(app, @GenerateExamplesButtonPushed, true);
            app.GenerateExamples.Text = 'Generate Table';
            app.ErrorsTab = uitab(app.TabGroup);
            app.ErrorsTab.Title = 'Errors';
            app.ExamplesEditFieldLabel = uilabel(app.ErrorsTab);
            app.ExamplesEditFieldLabel.Text = 'Examples';
            app.ExamplesEditField = uieditfield(app.ErrorsTab, 'numeric');
            app.PolydegreeEditFieldLabel = uilabel(app.ErrorsTab);
            app.PolydegreeEditFieldLabel.Text = 'Poly degree';
            app.PolydegreeEditField = uieditfield(app.ErrorsTab, 'numeric');
            app.StartButton = uibutton(app.ErrorsTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Output = uitable(app.ErrorsTab);
            app.Output.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Output.RowName = {};
            app.HeatmapTab = uitab(app.TabGroup);
            app.HeatmapTab.Title = 'Heatmap';
            app.PanelT = uipanel(app.HeatmapTab);
            app.PanelT.Title = 'Panel';
            app.PlotButton = uibutton(app.HeatmapTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.MaxSubintervalsSpinnerLabel = uilabel(app.HeatmapTab);
            app.MaxSubintervalsSpinnerLabel.Text = 'Max Subintervals';
            app.MaxSubintervalsSpinner = uispinner(app.HeatmapTab);
            app.PanelS = uipanel(app.HeatmapTab);
            app.PanelS.Title = 'Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project1GUI_Adam

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end